classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        find_root                matlab.ui.container.Tab
        EqnLabel                 matlab.ui.control.Label
        equation                 matlab.ui.control.EditField
        method                   matlab.ui.control.DropDown
        LabelEditField2          matlab.ui.control.Label
        File                     matlab.ui.control.EditField
        LabelNumericEditField    matlab.ui.control.Label
        iteration                matlab.ui.control.NumericEditField
        LabelNumericEditField2   matlab.ui.control.Label
        eps                      matlab.ui.control.NumericEditField
        LabelNumericEditField3   matlab.ui.control.Label
        xl                       matlab.ui.control.NumericEditField
        LabelNumericEditField4   matlab.ui.control.Label
        xu                       matlab.ui.control.NumericEditField
        solve_eqn                matlab.ui.control.Button
        solve_file               matlab.ui.control.Button
        error                    matlab.ui.control.TextArea
        LabelNumericEditField5   matlab.ui.control.Label
        X0                       matlab.ui.control.NumericEditField
        XIEditFieldLabel         matlab.ui.control.Label
        XI                       matlab.ui.control.NumericEditField
        linear_equation          matlab.ui.container.Tab
        FileNameLabel            matlab.ui.control.Label
        fileName                 matlab.ui.control.EditField
        epsLabel                 matlab.ui.control.Label
        gaussSeidleEps           matlab.ui.control.NumericEditField
        iterationEditFieldLabel  matlab.ui.control.Label
        gaussSeidleItr           matlab.ui.control.NumericEditField
        MethodDropDownLabel      matlab.ui.control.Label
        methodName               matlab.ui.control.DropDown
        SolveEqs                 matlab.ui.control.Button
        solveFile                matlab.ui.control.Button
        EquationsTextAreaLabel   matlab.ui.control.Label
        Equations                matlab.ui.control.TextArea
        X0TextAreaLabel          matlab.ui.control.Label
        initialGuess             matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.mainWindow.Position = [295 161 654 327];
        end

        % Button pushed function: solve_eqn
        function solve_eqnButtonPushed(app, event)
            eqn = app.equation.Value;
            f = char(eqn);
            val = app.method.Value;
            if strcmpi(val,'Bisection')
                [root,ea,i,xLower,xUpper] = bisection(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
            elseif strcmpi(val,'False-Position')
                [root,ea,i,xLower,xUpper] = false_position(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
            elseif strcmpi(val,'Fixed-Point')
                [g,x1,x2,ea] = fixed_point(f,app.x0.Value,app.iteration.Value,app.eps.Value);
            elseif strcmpi(val,'Newton-Raphason')
                [x1,ea] = Newton_Raphson(f,app.X0.Value,app.iteration.Value,app.eps.Value);
            elseif strcmpi(val,'Secant')
                [root,xr,itr,ea] = secant(f,app.X0.Value,app.XI.Value,app.eps.Value,app.iteration.Value);
            elseif strcmpi(val,'Bierge-Vieta')
                [root,b0,c1,xr,itr,ea] = biergevieta(f, app.X0.Value, app.eps.Value,app.iteration.Value);
            elseif stcmpi(val,'Steffensen')
                [p,p0,ea] = steffensen(f);
            elseif strcmpi(val,'All-Methods')
                [rootBi,eaBi,itrBi] = bisection(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
                [rootRf,eaRf,itrRf] = false_position(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
                [gFp,x1Fp,x2Fp,eaFp] = fixed_point(f,app.x0.Value,app.iteration.Value,app.eps.Value);
                [x1Nr,eaNr] = Newton_Raphson(f,app.X0.Value,app.iteration.Value,app.eps.Value);
                [rootSc,xrSc,itrSc,eaSc] = secant(f,app.X0.Value,app.XI.Value,app.eps.Value,app.iteration.Value);
                [rootBv,b0Bv,c1Bv,xrBv,itrBv,eaBv] = biergevieta(f, app.X0.Value, app.eps.Value,app.iteration.Value);
                [pSt,p0St,eaSt] = steffensen(f);
            end
            i = size(ea,2);
            if (i == 0)
                app.error.Value = 'no bracket! or diverge';
            else
                if strcmpi(val,'Bisection')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, xLower(t)];
                        y = [y, xUpper(t)];
                        y = [y, root(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h = {'itr' 'Xl' 'Xu' 'root' 'eps'};
                    fig = figure('name','bisection_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'False-Position')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, xLower(t)];
                        y = [y, xUpper(t)];
                        y = [y, root(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'i' 'Xl' 'Xu' 'root' 'eps'};
                    fig=figure('name','False-Position_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'Fixed-Point')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, x1(t)];
                        y = [y, x2(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'i' 'Xi' 'Xi+1' 'eps'};
                    fig=figure('name','Fixed-Point_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val, 'Newton-Raphason')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, x1(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'i' 'Xi' 'eps'};
                    fig=figure('name','Newton-Raphason_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'Secant')
                    x = [];
                    for t = 2:itr
                        y = [t-1];
                        y = [y, xr(t-1)];
                        y = [y, xr(t)];
                        y = [y, xr(t+1)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'itr' 'Xi-1' 'Xi' 'Xi+1' 'eps'};
                    fig=figure('name','Secant_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'Bierge-Vieta')
                    x = [];
                    for t = 2:itr
                        y = [t-1];
                        y = [y, xr(t-1)];
                        y = [y, b0(t)];
                        y = [y, c1(t)];
                        y = [y, xr(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'itr' 'Xi' 'B0' 'C0' 'Xi+1' 'eps'};
                    fig=figure('name','Bierge_Vieta_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                end
                
                if strcmpi(val,'Bisection')
                    plotting_bisection(f,root,xLower,xUpper,i);
                elseif strcmpi(val,'False-Position')
                    plotting_false_position(f,root,xLower,xUpper,i);
                elseif strcmpi(val,'Fixed-Point')
                    plotting_fixed_point(g,x1,x2,i);
                elseif strcmpi(val,'Newton-Raphason')
                    plotting_newton_raphason(f,x1);
                elseif strcmpi(val,'Secant')
                    plotting_secant(s,xr);
                elseif strcmpi(val,'All-Methods')
                    plotting_a (rootBi, rootRf, gFp, x1Nr, xrSc, xrBv);
                    plotting_b (eaBi, eaRf, eaFp, eaNr, eaSc, eaBv);
                end
                
            end
            
        end

        % Button pushed function: solve_file
        function solve_fileButtonPushed(app, event)
            file = app.File.Value;
            fileID = fopen(file,'r');
            tline = fgetl(fileID);
            f = vectorize(char(tline));
            tline = fgetl(fileID);
            val = tline;
            tline = fgetl(fileID);
            x = regexp(tline,'[-+]?[0-9]*\.?[0-9]*','match');
            if strcmpi(val,'Bisection') || strcmpi(val,'False-Position')
                xl = str2double(x{1});
                xu = str2double(x{2});
            else
                x0 = str2double(x{1});
            end
            tline = fgetl(fileID);
            i = 50;
            eps = 0.00001;
            if floor(str2double(tline)) == str2double(tline)
                i = str2double(tline);
            else
                eps = str2double(tline);
            end
            
            fclose(fileID);
            
            if strcmpi(val,'Bisection')
                [root,ea,i,xLower,xUpper] = bisection(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
            elseif strcmpi(val,'False-Position')
                [root,ea,i,xLower,xUpper] = false_position(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
            elseif strcmpi(val,'Fixed-Point')
                [g,x1,x2,ea] = fixed_point(f,app.x0.Value,app.iteration.Value,app.eps.Value);
            elseif strcmpi(val,'Newton-Raphason')
                [x1,ea] = Newton_Raphson(f,app.X0.Value,app.iteration.Value,app.eps.Value);
            elseif strcmpi(val,'Secant')
                [root,xr,itr,ea] = secant(f,app.X0.Value,app.XI.Value,app.eps.Value,app.iteration.Value);
            elseif strcmpi(val,'Bierge-Vieta')
                [root,b0,c1,xr,itr,ea] = biergevieta(f, app.X0.Value, app.eps.Value,app.iteration.Value);
            elseif stcmpi(val,'Steffensen')
                [p,p0,ea] = steffensen(f);
            elseif strcmpi(val,'All-Methods')
                [rootBi,eaBi,itrBi] = bisection(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
                [rootRf,eaRf,itrRf] = false_position(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
                [gFp,x1Fp,x2Fp,eaFp] = fixed_point(f,app.x0.Value,app.iteration.Value,app.eps.Value);
                [x1Nr,eaNr] = Newton_Raphson(f,app.X0.Value,app.iteration.Value,app.eps.Value);
                [rootSc,xrSc,itrSc,eaSc] = secant(f,app.X0.Value,app.XI.Value,app.eps.Value,app.iteration.Value);
                [rootBv,b0Bv,c1Bv,xrBv,itrBv,eaBv] = biergevieta(f, app.X0.Value, app.eps.Value,app.iteration.Value);
                [pSt,p0St,eaSt] = steffensen(f);
            end
            i = size(ea,2);
            if (i == 0)
                app.error.Value = 'no bracket! or diverge';
            else
                if strcmpi(val,'Bisection')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, xLower(t)];
                        y = [y, xUpper(t)];
                        y = [y, root(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h = {'itr' 'Xl' 'Xu' 'root' 'eps'};
                    fig = figure('name','bisection_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'False-Position')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, xLower(t)];
                        y = [y, xUpper(t)];
                        y = [y, root(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'i' 'Xl' 'Xu' 'root' 'eps'};
                    fig=figure('name','False-Position_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'Fixed-Point')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, x1(t)];
                        y = [y, x2(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'i' 'Xi' 'Xi+1' 'eps'};
                    fig=figure('name','Fixed-Point_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val, 'Newton-Raphason')
                    x = [];
                    for t = 1:i
                        y = [t];
                        y = [y, x1(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'i' 'Xi' 'eps'};
                    fig=figure('name','Newton-Raphason_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'Secant')
                    x = [];
                    for t = 2:itr
                        y = [t-1];
                        y = [y, xr(t-1)];
                        y = [y, xr(t)];
                        y = [y, xr(t+1)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'itr' 'Xi-1' 'Xi' 'Xi+1' 'eps'};
                    fig=figure('name','Secant_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                elseif strcmpi(val,'Bierge-Vieta')
                    x = [];
                    for t = 2:itr
                        y = [t-1];
                        y = [y, xr(t-1)];
                        y = [y, b0(t)];
                        y = [y, c1(t)];
                        y = [y, xr(t)];
                        y = [y, ea(t)];
                        x = [x; y];
                    end
                    h={'itr' 'Xi' 'B0' 'C0' 'Xi+1' 'eps'};
                    fig=figure('name','Bierge_Vieta_Answer');
                    uti = uitable(fig,'data',x,'columnname',h);
                    table_extent = get(uti,'Extent');
                    set(uti,'Position',[1 1 table_extent(3) table_extent(4)]);
                    figure_size = get(fig,'outerposition');
                    desired_fig_size = [figure_size(1) figure_size(2) table_extent(3)+15 table_extent(4)+65];
                    set(fig,'outerposition', desired_fig_size);
                end
                
                if strcmpi(val,'Bisection')
                    plotting_bisection(f,root,xLower,xUpper,i);
                elseif strcmpi(val,'False-Position')
                    plotting_false_position(f,root,xLower,xUpper,i);
                elseif strcmpi(val,'Fixed-Point')
                    plotting_fixed_point(g,x1,x2,i);
                elseif strcmpi(val,'Newton-Raphason')
                    plotting_newton_raphason(f,x1);
                elseif strcmpi(val,'Secant')
                    plotting_secant(s,xr);
                elseif strcmpi(val,'All-Methods')
                    plotting_a (rootBi, rootRf, gFp, x1Nr, xrSc, xrBv);
                    plotting_b (eaBi, eaRf, eaFp, eaNr, eaSc, eaBv);
                end
                
            end
        end

        % Button pushed function: SolveEqs
        function SolveEqsButtonPushed(app, event)
            eq = app.Equations.Value;
            for i=1:size(eq,1)
                equations{i,1} = str2sym(eq{i,1});
            end
            funcs = cell2sym(equations);
            val = app.methodName.Value;
            if strcmpi(val,'Gauss_Elimination')
                [x] = Gauss_Elimination(funcs,size(eq,1));
            elseif strcmpi(val,'Gauss_jordan')
                [x] = Gauss_jordan(funcs,size(eq,1));
            elseif strcmpi(val,'LU_Decomposition')
                [x] = LU_Decomposition(funcs,size(eq,1));
            elseif strcmpi(val,'Gauss_Seidel')
                x0 = zeros(1);
                for i=1:size(app.initialGuess.Value,1)
                    x0(i) = str2double(app.initialGuess.Value{i,1});
                end
                [x,err,j] = Gauss_Seidel(funcs,x0,app.iteration.Value,app.eps.Value);
                plotting_Gauss_Seidel(x);
            elseif strcmpi(val,'All-Methods')
                [xEle] = Gauss_Elimination(funcs,size(eq,1));
                [xJor] = Gauss_jordan(funcs,size(eq,1));
                [xLU] = LU_Decomposition(funcs,size(eq,1));
                x0 = zeros(1);
                for i=1:size(app.initialGuess.Value,1)
                    x0(i) = str2double(app.initialGuess.Value{i,1});
                end
                [xSei,err,j] = Gauss_Seidel(funcs,x0,app.iteration.Value,app.eps.Value);
                plotting_Gauss_Seidel(xSei);
            end
            
        end

        % Button pushed function: solveFile
        function solveFileButtonPushed(app, event)
            name = app.fileName.Value;
            fileID = fopen(name,'r');
            numberOfEquations = fgetl(fileID);
            noe = str2double(numberOfEquations);
            for i=1:noe
                equations{i,1} = str2sym(fgetl(fileID));
            end
            funcs = cell2sym(equations);
            val = fgetl(fileID);
            if strcmpi(val,'Gauss_Elimination')
                [x] = Gauss_Elimination(funcs,noe);
            elseif strcmpi(val,'Gauss_jordan')
                [x] = Gauss_jordan(funcs,noe);
            elseif strcmpi(val,'LU_Decomposition')
                [x] = LU_Decomposition(funcs,noe);
            elseif strcmpi(val,'Gauss_Seidel')
                x0 = str2num(fgetl(fileID));
                [x,err,j] = Gauss_Seidel(funcs,x0,50,0.00001);
                plotting_Gauss_Seidel(x);
            elseif strcmpi(val,'All-Methods')
                [xEle] = Gauss_Elimination(funcs,noe);
                [xJor] = Gauss_jordan(funcsnoe);
                [xLU] = LU_Decomposition(funcs,noe);
                 x0 = str2num(fgetl(fileID));
                [xSei,err,j] = Gauss_Seidel(funcs,x0,50,0.00001);
                plotting_Gauss_Seidel(xSei);
            end
            fclose(fileID);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.find_root = uitab(app.TabGroup);
            app.find_root.Title = 'Part_1';
            app.EqnLabel = uilabel(app.find_root);
            app.EqnLabel.Text = 'Eqn';
            app.equation = uieditfield(app.find_root, 'text');
            app.method = uidropdown(app.find_root);
            app.method.Items = {'Bisection', 'False-Position', 'Fixed-Point', 'Newton-Raphason', 'Secant', 'Bierge-Vieta', 'Steffensen', 'All-Methods'};
            app.method.Editable = 'on';
            app.method.Value = 'Bisection';
            app.LabelEditField2 = uilabel(app.find_root);
            app.LabelEditField2.Text = 'File';
            app.File = uieditfield(app.find_root, 'text');
            app.LabelNumericEditField = uilabel(app.find_root);
            app.LabelNumericEditField.Text = 'iteration';
            app.iteration = uieditfield(app.find_root, 'numeric');
            app.iteration.Limits = [0 Inf];
            app.iteration.Value = 50;
            app.LabelNumericEditField2 = uilabel(app.find_root);
            app.LabelNumericEditField2.Text = 'eps';
            app.eps = uieditfield(app.find_root, 'numeric');
            app.eps.Limits = [0 1];
            app.eps.Value = 1e-05;
            app.LabelNumericEditField3 = uilabel(app.find_root);
            app.LabelNumericEditField3.Text = 'XL';
            app.xl = uieditfield(app.find_root, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.find_root);
            app.LabelNumericEditField4.Text = 'XU';
            app.xu = uieditfield(app.find_root, 'numeric');
            app.solve_eqn = uibutton(app.find_root, 'push');
            app.solve_eqn.ButtonPushedFcn = createCallbackFcn(app, @solve_eqnButtonPushed, true);
            app.solve_eqn.Text = 'Solve Eqn.';
            app.solve_file = uibutton(app.find_root, 'push');
            app.solve_file.ButtonPushedFcn = createCallbackFcn(app, @solve_fileButtonPushed, true);
            app.solve_file.Text = 'Solve File';
            app.error = uitextarea(app.find_root);
            app.error.Editable = 'off';
            app.error.Value = {'Error !'};
            app.LabelNumericEditField5 = uilabel(app.find_root);
            app.LabelNumericEditField5.Text = 'X0';
            app.X0 = uieditfield(app.find_root, 'numeric');
            app.XIEditFieldLabel = uilabel(app.find_root);
            app.XIEditFieldLabel.Text = 'XI';
            app.XI = uieditfield(app.find_root, 'numeric');
            app.linear_equation = uitab(app.TabGroup);
            app.linear_equation.Title = 'Part_2';
            app.FileNameLabel = uilabel(app.linear_equation);
            app.FileNameLabel.Text = 'FileName';
            app.fileName = uieditfield(app.linear_equation, 'text');
            app.epsLabel = uilabel(app.linear_equation);
            app.epsLabel.Text = 'eps';
            app.gaussSeidleEps = uieditfield(app.linear_equation, 'numeric');
            app.gaussSeidleEps.Value = 1e-05;
            app.iterationEditFieldLabel = uilabel(app.linear_equation);
            app.iterationEditFieldLabel.Text = 'iteration';
            app.gaussSeidleItr = uieditfield(app.linear_equation, 'numeric');
            app.gaussSeidleItr.Value = 50;
            app.MethodDropDownLabel = uilabel(app.linear_equation);
            app.MethodDropDownLabel.Text = 'Method';
            app.methodName = uidropdown(app.linear_equation);
            app.methodName.Items = {'Gauss_Elimination', 'Gauss_jordan', 'LU_Decomposition', 'Gauss_Seidel', 'All-Methods'};
            app.methodName.Value = 'Gauss_Elimination';
            app.SolveEqs = uibutton(app.linear_equation, 'push');
            app.SolveEqs.ButtonPushedFcn = createCallbackFcn(app, @SolveEqsButtonPushed, true);
            app.SolveEqs.Text = 'SolveEqs';
            app.solveFile = uibutton(app.linear_equation, 'push');
            app.solveFile.ButtonPushedFcn = createCallbackFcn(app, @solveFileButtonPushed, true);
            app.solveFile.Text = 'solveFile';
            app.EquationsTextAreaLabel = uilabel(app.linear_equation);
            app.EquationsTextAreaLabel.Text = 'Equations';
            app.Equations = uitextarea(app.linear_equation);
            app.X0TextAreaLabel = uilabel(app.linear_equation);
            app.X0TextAreaLabel.Text = 'X0';
            app.initialGuess = uitextarea(app.linear_equation);
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end